classdef NASA_Test_1_MK2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SetupPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BasicChecksPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        CheckButton                     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        EditField                       matlab.ui.control.EditField
        WiringCheckLabel                matlab.ui.control.Label
        TubingCheckLabel                matlab.ui.control.Label
        CheckButton_2                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        ManualCheckLabel                matlab.ui.control.Label
        FlowSettingsPanel               matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        O2Label                         matlab.ui.control.Label
        O2Spinner                       matlab.ui.control.Spinner
        N2SpinnerLabel                  matlab.ui.control.Label
        N2Spinner                       matlab.ui.control.Spinner
        WeightScaleResetPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ResetScaleButton                matlab.ui.control.Button
        InitialWeightLabel              matlab.ui.control.Label
        gEditFieldLabel                 matlab.ui.control.Label
        gEditField                      matlab.ui.control.NumericEditField
        ChamberBackfillPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        kPaEditFieldLabel               matlab.ui.control.Label
        kPaEditField                    matlab.ui.control.NumericEditField
        PressureLabel                   matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        EvacuateChamberButton           matlab.ui.control.StateButton
        RefillChamberButton             matlab.ui.control.StateButton
        StartRunPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        IgnitionButton                  matlab.ui.control.StateButton
        StartRecordingDataButton        matlab.ui.control.StateButton
        RetractIgniterButton            matlab.ui.control.StateButton
        ConsoleTextAreaLabel            matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
        CenterPanel                     matlab.ui.container.Panel
        LivestreamPanel                 matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        LiveGraphsTab                   matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        TemperatureTab                  matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        RadiativeFluxTab                matlab.ui.container.Tab
        GridLayout15_2                  matlab.ui.container.GridLayout
        UIAxes_2                        matlab.ui.control.UIAxes
        ChangeinWeightTab               matlab.ui.container.Tab
        GridLayout15_3                  matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        LiveTables                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        VitalsWarningsPanel             matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        PressureSensorPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ChamberPressureEditFieldLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        kPaLabel                        matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        SensorTemperatureEditFieldLabel  matlab.ui.control.Label
        SensorTemperatureEditField      matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        PaperIgnitedPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        PaperIgnitedCheckBox            matlab.ui.control.CheckBox
        MassFlowControllersPanel        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        VolumetricFlowO2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowO2EditField       matlab.ui.control.NumericEditField
        ULabel                          matlab.ui.control.Label
        VolumetricFlowN2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowN2EditField       matlab.ui.control.NumericEditField
        ULabel_2                        matlab.ui.control.Label
        MassFlowO2EditFieldLabel        matlab.ui.control.Label
        MassFlowO2EditField             matlab.ui.control.NumericEditField
        MassFlowN2EditFieldLabel        matlab.ui.control.Label
        MassFlowN2EditField             matlab.ui.control.NumericEditField
        VolumetricFlowTotEditFieldLabel  matlab.ui.control.Label
        VolumetricFlowTotEditField      matlab.ui.control.NumericEditField
        MassFlowTotalEditFieldLabel     matlab.ui.control.Label
        MassFlowTotalEditField          matlab.ui.control.NumericEditField
        InletVelocityEditFieldLabel     matlab.ui.control.Label
        InletVelocityEditField          matlab.ui.control.NumericEditField
        ULabel_3                        matlab.ui.control.Label
        ULabel_4                        matlab.ui.control.Label
        ULabel_5                        matlab.ui.control.Label
        ULabel_6                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        DataTranscriptionPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ChooseFolderButton              matlab.ui.control.Button
        FolderSelectedEditFieldLabel    matlab.ui.control.Label
        FolderSelectedEditField         matlab.ui.control.EditField
        DocumentTitleEditFieldLabel     matlab.ui.control.Label
        DocumentTitleEditField          matlab.ui.control.EditField
        SendDataButton                  matlab.ui.control.Button
        StopRunButton                   matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        f % Description
        ar %
        running %
        addrs%
        adc %
        WireReady
        TubeReady
        FlowReady
        ScaleReady
        Tab
        AllData
        Sensors
        Sensor_report
        Pressure_running
    end
    
    methods (Access = public)
        
        function Ready1(app)
            if (app.WireReady == 1) && (app.TubeReady == 1) && ...
                    (app.FlowReady == 1) && (app.ScaleReady == 1)
                set(app.ChamberBackfillPanel,'visible','on')
            end
        end
        
        function Ready2(app)
            if (app.ChamberReady == 1)
                set(app.StartRunPanel,'visible','on')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear; clc;
            
            app.addrs = 1;
        end

        % Size changed function: ChamberBackfillPanel
        function ChamberBackfillPanelSizeChanged(app, event)
            position = app.ChamberBackfillPanel.Position;
            
        end

        % Callback function
        function ReadyButtonPushed(app, event)
            set(app.ChamberBackfillPanel,'visible','on')
            set(app.StartRunPanel,'visible','on')
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            selpath =  uigetdir;
            app.FolderSelectedEditField.Value = selpath;
        end

        % Callback function
        function IgnitionButtonPushed(app, event)
            app.running = 1;
            % Clock start
            t = seconds(0);
            
            %Initial Readings here
            v1 = readVoltage(app.adc,0);
            %T = app.Sensors.Thermocouple.readHotJunc();
            %F = app.Sensors.Radiometer.Read();
            %W = app.Sensors.Scale.Read();            
            
            t_num = seconds(t);
            %Initiate Graphs/Tables=
            app.Tab = {t_num,v1};
            app.UITable.Data = [app.Tab];
            line = line(app.UIAxes,t,v1);
            %line1 = line(app.UIAxes,t,T);
            %line2 = line(app.UIAxes_2,t,F);
            %line3 = line(app.UIAxes_3,t,W);
            
            ylim(app.UIAxes,[3.41,3.43]);
            
            %Continuously runs, updates graphs/tables
            pause_len = .1;
            t(2) = t(1) + seconds(pause_len);
            i = 2;
            while (app.running)
                v1(i) = readVoltage(app.adc, 0);
                %T(i) = app.Sensors.Thermocouple.readHotJunc();
                %F(i) = app.Sensors.Radiometer.Read();
                %W(i) = app.Sensors.Scale.Read();    
                pause(pause_len)
                
                t_num(i) = seconds(t(i));
                line.XData = [line.XData t(i)];
                line.YData = [line.YData v1(i)];
                
                %line1.XData = [line1.XData t(i)];
                %line2.XData = [line2.XData t(i)];
                %line3.XData = [line3.XData t(i)];
                
                %line1.YData = [line.YData T(i)];
                %line2.YData = [line.YData F(i)];
                %line3.YData = [line.YData W(i)];
                
                app.Tab = [app.Tab;{t_num(i),v1(i)}];
                app.UITable.Data = [app.Tab];
                
                if t<seconds(60)
                    xlim(app.UIAxes,[seconds(0),seconds(60)])
                else
                    xlim(app.UIAxes,[t(i)-seconds(60),t(i)])
                end
                
                
                i = i+1;
                t(i) = t(i-1) + seconds(pause_len);
            end
            
        end

        % Callback function
        function RetractIgniterButtonPushed(app, event)
            app.running = 0;
            app.Transducers.ArmMotor.Run(); %Play with the inputs later Duration,DutyCycle
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            %{
            app.f = Func_Lib;
            app.ar = app.f.ConnectArduino();
            %[app.Sensors,app.Transducers] = app.f.Initialize(app.ar);
            %app.Sensor_report = app.f.SensorCheck();
            
            
            app.addrs = scanI2CBus(app.ar);
            app.adc = ads1115(app.ar, app.addrs{1});
            app.adc.VoltageScale = 4.096;
            %}
            
            %Change to whatever COM port the Arduino is connected to
            app.s = serialport("COM6",9600); 
            %This is the # of seconds the app waits between measurements
            app.dataRes = 1
            
            app.FlowReady = 0;
            app.TubeReady = 0;
            app.ScaleReady = 0;
            app.WireReady = 1;
            
            app.Lamp.Color = 'green';


            Ready1(app);
            
            %Run livestream
            
        end

        % Button pushed function: CheckButton_2
        function CheckButton_2Pushed(app, event)
            app.TubeReady = 1;
            app.Lamp_2.Color = 'green';
            Ready1(app);
        end

        % Value changed function: O2Spinner
        function O2SpinnerValueChanged(app, event)
            value = app.O2Spinner.Value;
            if (value<=100) && (value>=0)
                app.N2Spinner.Value = 100-value;
            end
            
            %Need to calculate the flow for O2 and N2 here
            
            app.FlowReady = 1;
            Ready1(app);
        end

        % Button pushed function: ResetScaleButton
        function ResetScaleButtonPushed(app, event)
            app.ScaleReady = 1;
            Ready1(app);
            
            
        end

        % Button pushed function: SendDataButton
        function SendDataButtonPushed(app, event)
            if app.running == 1
                return
            end
            
            if ~isempty(app.DocumentTitleEditField.Value)
                fullname = append(app.FolderSelectedEditField.Value, app.DocumentTitleEditField.Value);
                
                xlswrite(fullname,app.AllData);
            end
        end

        % Callback function
        function EvacuateChamberButtonPushed(app, event)
            %{
            app.Pressure_running = 1;
            while app.Pressure_running
                %{ 
                [P,T_P] = app.Sensors.Pressure.Read();
                
                if P < -Insert Vacuum Pressure-
                    app.Lamp_3.Color = 'green';
                end
                
                if P > -Insert Max Chamber Pressure Here-
                    app.Lamp_7.Color = 'red';
                end
                
                if T_P > -Insert Max Chamber Temp Here- Help prevent damage to sensors-
                    app.Lamp_8.Color = 'red';
                end
                
                app.ChamberPressureEditField.Value = P;
                app.SensorTemperatureEditField.Value = T_P;
                
                pause(.1)
                %}
            end
            %}
            
            
        end

        % Value changed function: PaperIgnitedCheckBox
        function PaperIgnitedCheckBoxValueChanged(app, event)
            value = app.PaperIgnitedCheckBox.Value;
            
        end

        % Value changed function: EvacuateChamberButton
        function EvacuateChamberButtonValueChanged(app, event)
            %{
            This is going to initiate the main event loop.
            
            From the loop, all other state buttons will be checked and resulting actions will be exectuted.        
            %}
            value = app.EvacuateChamberButton.Value;
            
            app.running = 1;
            
            %This starts the  arduino's main loop, in which it sends data 
            write(app.s,'B',"uint8"); 
            
            %Initiate Data matrix: [T1,T2,P,T_P,W,L]
            data = [];
            
            %flow is not set yet
            flowS = false;
            
            t = seconds(0);            
            i = 0;
            while app.running
                %Read arduino and split data into component parts
                b = readline(app.s);
                a = cellstr(b);
                st = a{1}(1:end-1);
                c = strsplit(st,"|"); 
                
                %Creates Row cell array and full data array
                row = {};
                for j = 1:length(c)
                    row{j} = str2double(c{j});
                    %data(i,j) = row(j);
                end 
                
                %Unpack row into variables
                [T1,T2,P,T_P,W,L] = row{:};
                
                %{                
                %This updates the temperature and pressure fields of the GUI
                app.ChamberPressureEditField.Value = P;
                app.kPaEditField = P;
                app.SensorTemperatureEditField.Value = T_P;
                
                %This lets you know that the Pressure is low enough to
                refill the chamber
                if app.Lamp_3.Color == 'red'          
                    if P < -Insert Vacuum Pressure-
                        app.Lamp_3.Color = 'green';
                        app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "Desired vacuum pressure achieved. May begin refilling chamber";
                    else
                        pause(app.dataRes);
                        continue;
                    end
                end
                
                %This lets you know if the pressure in the chamber is
                getting too high
                if P > -Insert Max Chamber Pressure Here-
                    app.Lamp_7.Color = 'red';
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "*Chamber ambient pressure too high*";
                end
                
                %This lets you know if the ambient temperature in the chamber is
                getting too high
                if T_P > -Insert Max Chamber Temp Here- Help prevent damage to sensors-
                    app.Lamp_8.Color = 'red';
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "*Chamber ambient temperature too high*";
                end
                %}
                
                if app.RefillChamberButton.Value && ~sFlow 
                    %{
                    st1 = setFlow(app.flowO2,"A")
                    st2 = setFlow(app.flowN2,"B")
                    %}
                    
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + st1 + "\n" + st2
                    
                    %Makes sure the flow isn't reset each iteration 
                    sFlow = true;
                else
                    pause(app.dataRes);
                    continue;                        
                end
                
                %{
                %If pressure is within testing range, indicates the chamber
                is refilled
                if (P > -Required chamber Pressure 1-) && (P < -Required chamber Pressure 2-)
                    if app.Lamp_4.Color == 'red';         
                        app.Lamp_4.Color = 'green';
                        app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "Desired conditions met. May initiate Test";
                    end
                else
                    app.Lamp_4.Color = 'red';
                    pause(app.dataRes);
                    continue;
                end    
                %}
                
                %Checks if ignition lamp is off and if the button is
                %pressed before telling the arduino to ignite the sample
                % (and maybe put the igniter down)
                if (app.Lamp_5.Color == 'red') && (app.IgnitionButton.Value)
                    app.Lamp_5.Color = 'green';
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "Ignition starting...";
                    
                    %write(s,'F',"uint8");
                    write(s,'O',"uint8");                    
                end
                
                %This adds the instrument data to the data output array and
                %the dynamic graphs/tables only once the StartRecordingDataButton is pressed
                if app.StartRecordingDataButton.Value
                    if  app.Lamp_9.Color == 'red'
                        app.Lamp_9.Color = 'green';
                    end
                    
                    i = i+1;
                    %Adds each line to the data matrix
                    for j = 1:length(c)
                        data(i,j) = row{j};
                    end
                    
                    %Adds to the graphs/tables
                    if i == 1
                        %If its the first iteration initiates the lines and
                        %adds them to their graphs
                        t_num = seconds(t);%<----Check this 
                        
                        %Initiate Graphs/Tables
                        app.Tab = {t_num, T1 , L, W};
                        app.UITable.Data = [app.Tab];
                        line1 = line(app.UIAxes,t,T1);
                        line2 = line(app.UIAxes_2,t,L);
                        line3 = line(app.UIAxes_3,t,W);
            
                        ylim(app.UIAxes,[3.41,3.43]);
                    else
                        t_num(i) = seconds(t(i));
                
                        line1.XData = [line1.XData t(i)];
                        line2.XData = [line2.XData t(i)];
                        line3.XData = [line3.XData t(i)];
                
                        line1.YData = [line.YData T1];
                        line2.YData = [line.YData L];
                        line3.YData = [line.YData W];
                
                        app.Tab = [app.Tab;{t_num(i),v1(i)}];
                        app.UITable.Data = [app.Tab];
                            
                        %Makes sure you only see 60 time points at a time
                        if t<seconds(60)
                            xlim(app.UIAxes,[seconds(0),seconds(60)])
                        else
                            xlim(app.UIAxes,[t(i)-seconds(60),t(i)])
                        end               


                    end
                    
                    t(i+1) = t(i) + seconds(app.dataRes);
                    
                end
                
                %Checks if retract igniter lamp is off and if the button is
                %pressed before telling the arduino to cut the ignition circuit
                %and retract the igniter
                if (app.Lamp_6.Color == 'red') && (app.RetractIgniterButton.Value)
                    app.Lamp_6.Color = 'green';
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "Retracting Ignitor...";                    
                    
                    write(app.s,'C',"uint8");   
                    %May need to add a pause here 
                    write(app.s,'R',"uint8");
                end  


                %This button breaks the event loop and the arduino's event loop. 
                %Button Default is off 
                if app.StopRunButton.Value
                    app.running = 0;
                    write(app.s,'E',"uint8");                    
                    app.ConsoleTextArea.Value = app.ConsoleTextArea.Value + "\n" + "Stopping all processes...";
                end
                
                %Pause a certain number of seconds
                pause(app.dataRes)
                
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {970, 970, 970};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {970, 970};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {304, '1x', 297};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetupPanel = uipanel(app.LeftPanel);
            app.SetupPanel.Title = 'Setup';
            app.GridLayout2 = uigridlayout(app.SetupPanel);
            app.BasicChecksPanel = uipanel(app.GridLayout2);
            app.BasicChecksPanel.Title = 'Basic Checks';
            app.GridLayout3 = uigridlayout(app.BasicChecksPanel);
            app.CheckButton = uibutton(app.GridLayout3, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Lamp = uilamp(app.GridLayout3);
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Value = 'Not Run';
            app.WiringCheckLabel = uilabel(app.GridLayout3);
            app.WiringCheckLabel.Text = 'Wiring Check';
            app.TubingCheckLabel = uilabel(app.GridLayout3);
            app.TubingCheckLabel.Text = 'Tubing Check';
            app.CheckButton_2 = uibutton(app.GridLayout3, 'push');
            app.CheckButton_2.ButtonPushedFcn = createCallbackFcn(app, @CheckButton_2Pushed, true);
            app.CheckButton_2.Text = 'Check';
            app.Lamp_2 = uilamp(app.GridLayout3);
            app.ManualCheckLabel = uilabel(app.GridLayout3);
            app.ManualCheckLabel.Text = 'Manual Check';
            app.FlowSettingsPanel = uipanel(app.GridLayout2);
            app.FlowSettingsPanel.Title = 'Flow Settings';
            app.GridLayout7 = uigridlayout(app.FlowSettingsPanel);
            app.O2Label = uilabel(app.GridLayout7);
            app.O2Label.Text = 'O2 %';
            app.O2Spinner = uispinner(app.GridLayout7);
            app.O2Spinner.ValueChangedFcn = createCallbackFcn(app, @O2SpinnerValueChanged, true);
            app.N2SpinnerLabel = uilabel(app.GridLayout7);
            app.N2SpinnerLabel.Text = 'N2 %';
            app.N2Spinner = uispinner(app.GridLayout7);
            app.WeightScaleResetPanel = uipanel(app.GridLayout2);
            app.WeightScaleResetPanel.Title = 'Weight Scale Reset';
            app.GridLayout5 = uigridlayout(app.WeightScaleResetPanel);
            app.ResetScaleButton = uibutton(app.GridLayout5, 'push');
            app.ResetScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScaleButtonPushed, true);
            app.ResetScaleButton.Text = 'Reset Scale';
            app.InitialWeightLabel = uilabel(app.GridLayout5);
            app.InitialWeightLabel.Text = 'Initial Weight';
            app.gEditFieldLabel = uilabel(app.GridLayout5);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ChamberBackfillPanel = uipanel(app.GridLayout2);
            app.ChamberBackfillPanel.Title = 'Chamber Backfill';
            app.ChamberBackfillPanel.SizeChangedFcn = createCallbackFcn(app, @ChamberBackfillPanelSizeChanged, true);
            app.GridLayout6 = uigridlayout(app.ChamberBackfillPanel);
            app.kPaEditFieldLabel = uilabel(app.GridLayout6);
            app.kPaEditFieldLabel.Text = 'kPa';
            app.kPaEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PressureLabel = uilabel(app.GridLayout6);
            app.PressureLabel.Text = 'Pressure:';
            app.Lamp_3 = uilamp(app.GridLayout6);
            app.Lamp_4 = uilamp(app.GridLayout6);
            app.EvacuateChamberButton = uibutton(app.GridLayout6, 'state');
            app.EvacuateChamberButton.ValueChangedFcn = createCallbackFcn(app, @EvacuateChamberButtonValueChanged, true);
            app.EvacuateChamberButton.Text = 'Evacuate Chamber';
            app.RefillChamberButton = uibutton(app.GridLayout6, 'state');
            app.RefillChamberButton.Text = 'Refill Chamber';
            app.StartRunPanel = uipanel(app.GridLayout2);
            app.StartRunPanel.Title = 'Start Run';
            app.GridLayout8 = uigridlayout(app.StartRunPanel);
            app.Lamp_5 = uilamp(app.GridLayout8);
            app.Lamp_6 = uilamp(app.GridLayout8);
            app.Lamp_9 = uilamp(app.GridLayout8);
            app.IgnitionButton = uibutton(app.GridLayout8, 'state');
            app.IgnitionButton.Text = 'Ignition';
            app.StartRecordingDataButton = uibutton(app.GridLayout8, 'state');
            app.StartRecordingDataButton.Text = 'Start Recording Data';
            app.RetractIgniterButton = uibutton(app.GridLayout8, 'state');
            app.RetractIgniterButton.Text = 'Retract Igniter';
            app.ConsoleTextAreaLabel = uilabel(app.LeftPanel);
            app.ConsoleTextAreaLabel.Text = 'Console:';
            app.ConsoleTextArea = uitextarea(app.LeftPanel);
            app.ConsoleTextArea.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.LivestreamPanel = uipanel(app.CenterPanel);
            app.LivestreamPanel.Title = 'Livestream';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.LiveGraphsTab = uitab(app.TabGroup);
            app.LiveGraphsTab.Title = 'Live Graphs';
            app.GridLayout14 = uigridlayout(app.LiveGraphsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout14);
            app.TemperatureTab = uitab(app.TabGroup2);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout15 = uigridlayout(app.TemperatureTab);
            app.UIAxes = uiaxes(app.GridLayout15);
            title(app.UIAxes, 'Temperature vs Time')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'T(C)')
            app.RadiativeFluxTab = uitab(app.TabGroup2);
            app.RadiativeFluxTab.Title = 'Radiative Flux';
            app.GridLayout15_2 = uigridlayout(app.RadiativeFluxTab);
            app.UIAxes_2 = uiaxes(app.GridLayout15_2);
            title(app.UIAxes_2, 'Radiative Flux vs Time')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'Radiaitive Flux(mW/cm^2)')
            app.ChangeinWeightTab = uitab(app.TabGroup2);
            app.ChangeinWeightTab.Title = 'Change in Weight';
            app.GridLayout15_3 = uigridlayout(app.ChangeinWeightTab);
            app.UIAxes2 = uiaxes(app.GridLayout15_3);
            title(app.UIAxes2, 'Change in Weight vs Time')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Change in Weight(g)')
            app.LiveTables = uitab(app.TabGroup);
            app.LiveTables.Title = 'Live Tables';
            app.GridLayout16 = uigridlayout(app.LiveTables);
            app.UITable = uitable(app.GridLayout16);
            app.UITable.ColumnName = {'Time'; 'Temperature'; 'Radiative Flux'; 'Change in Weight'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.VitalsWarningsPanel = uipanel(app.RightPanel);
            app.VitalsWarningsPanel.Title = 'Vitals??/Warnings';
            app.GridLayout10 = uigridlayout(app.VitalsWarningsPanel);
            app.PressureSensorPanel = uipanel(app.GridLayout10);
            app.PressureSensorPanel.Title = 'Pressure Sensor';
            app.GridLayout11 = uigridlayout(app.PressureSensorPanel);
            app.ChamberPressureEditFieldLabel = uilabel(app.GridLayout11);
            app.ChamberPressureEditFieldLabel.Text = 'Chamber Pressure';
            app.ChamberPressureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.kPaLabel = uilabel(app.GridLayout11);
            app.kPaLabel.Text = 'kPa';
            app.Lamp_7 = uilamp(app.GridLayout11);
            app.SensorTemperatureEditFieldLabel = uilabel(app.GridLayout11);
            app.SensorTemperatureEditFieldLabel.Text = 'Sensor Temperature';
            app.SensorTemperatureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.KLabel = uilabel(app.GridLayout11);
            app.KLabel.Text = 'K';
            app.Lamp_8 = uilamp(app.GridLayout11);
            app.PaperIgnitedPanel = uipanel(app.GridLayout10);
            app.PaperIgnitedPanel.Title = 'Paper Ignited';
            app.GridLayout12 = uigridlayout(app.PaperIgnitedPanel);
            app.PaperIgnitedCheckBox = uicheckbox(app.GridLayout12);
            app.PaperIgnitedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PaperIgnitedCheckBoxValueChanged, true);
            app.PaperIgnitedCheckBox.Text = 'Paper Ignited';
            app.MassFlowControllersPanel = uipanel(app.GridLayout10);
            app.MassFlowControllersPanel.Title = 'Mass Flow Controllers';
            app.GridLayout13 = uigridlayout(app.MassFlowControllersPanel);
            app.VolumetricFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowO2EditFieldLabel.Text = 'Volumetric Flow O2';
            app.VolumetricFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.ULabel = uilabel(app.GridLayout13);
            app.ULabel.Text = 'U';
            app.VolumetricFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowN2EditFieldLabel.Text = 'Volumetric Flow N2';
            app.VolumetricFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.ULabel_2 = uilabel(app.GridLayout13);
            app.ULabel_2.Text = 'U';
            app.MassFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowO2EditFieldLabel.Text = 'Mass Flow O2';
            app.MassFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowN2EditFieldLabel.Text = 'Mass Flow N2';
            app.MassFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.VolumetricFlowTotEditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowTotEditFieldLabel.Text = 'Volumetric Flow Tot.';
            app.VolumetricFlowTotEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowTotalEditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowTotalEditFieldLabel.Text = 'Mass Flow Total';
            app.MassFlowTotalEditField = uieditfield(app.GridLayout13, 'numeric');
            app.InletVelocityEditFieldLabel = uilabel(app.GridLayout13);
            app.InletVelocityEditFieldLabel.Text = 'Inlet Velocity';
            app.InletVelocityEditField = uieditfield(app.GridLayout13, 'numeric');
            app.ULabel_3 = uilabel(app.GridLayout13);
            app.ULabel_3.Text = 'U';
            app.ULabel_4 = uilabel(app.GridLayout13);
            app.ULabel_4.Text = 'U';
            app.ULabel_5 = uilabel(app.GridLayout13);
            app.ULabel_5.Text = 'U';
            app.ULabel_6 = uilabel(app.GridLayout13);
            app.ULabel_6.Text = 'U';
            app.msLabel = uilabel(app.GridLayout13);
            app.msLabel.Text = 'm/s';
            app.DataTranscriptionPanel = uipanel(app.RightPanel);
            app.DataTranscriptionPanel.Title = 'Data Transcription';
            app.GridLayout9 = uigridlayout(app.DataTranscriptionPanel);
            app.ChooseFolderButton = uibutton(app.GridLayout9, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FolderSelectedEditFieldLabel = uilabel(app.GridLayout9);
            app.FolderSelectedEditFieldLabel.Text = 'Folder Selected:';
            app.FolderSelectedEditField = uieditfield(app.GridLayout9, 'text');
            app.DocumentTitleEditFieldLabel = uilabel(app.GridLayout9);
            app.DocumentTitleEditFieldLabel.Text = 'Document Title:';
            app.DocumentTitleEditField = uieditfield(app.GridLayout9, 'text');
            app.SendDataButton = uibutton(app.GridLayout9, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.StopRunButton = uibutton(app.RightPanel, 'state');
            app.StopRunButton.Text = 'Stop Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NASA_Test_1_MK2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end